classdef inputapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ProjectDurationyearsSliderLabel  matlab.ui.control.Label
        ProjectDurationyearsSlider      matlab.ui.control.Slider
        PipelineLengthkmSliderLabel     matlab.ui.control.Label
        PipelineLengthkmSlider          matlab.ui.control.Slider
        DesignAnnualFlowMtaSliderLabel  matlab.ui.control.Label
        DesignAnnualFlowMtaSlider       matlab.ui.control.Slider
        EnableSteelSettingsSwitchLabel  matlab.ui.control.Label
        EnableSteelSettingsSwitch       matlab.ui.control.ToggleSwitch
        MildSteelLabel                  matlab.ui.control.Label
        MildSteelEditField              matlab.ui.control.NumericEditField
        StainlessSteelLabel             matlab.ui.control.Label
        StainlessSteelEditField         matlab.ui.control.NumericEditField
        UpdateButton                    matlab.ui.control.Button
        DiscountRateKnobLabel           matlab.ui.control.Label
        DiscountRateKnob                matlab.ui.control.Knob
        UtilizationRateFlowLabel        matlab.ui.control.Label
        UtilizationRateFlowKnob         matlab.ui.control.Knob
        PipelinecompositionOKLabel      matlab.ui.control.Label
        OnshoreRoadSpinnerLabel         matlab.ui.control.Label
        OnshoreRoadSpinner              matlab.ui.control.Spinner
        OnshorePipeSpinnerLabel         matlab.ui.control.Label
        OnshorePipeSpinner              matlab.ui.control.Spinner
        OnshoreOtherSpinnerLabel        matlab.ui.control.Label
        OnshoreOtherSpinner             matlab.ui.control.Spinner
        OffshorePipeSpinnerLabel        matlab.ui.control.Label
        OffshorePipeSpinner             matlab.ui.control.Spinner
        OffshoreOtherSpinnerLabel       matlab.ui.control.Label
        OffshoreOtherSpinner            matlab.ui.control.Spinner
        OnshoreRuralEditFieldLabel      matlab.ui.control.Label
        OnshoreRuralEditField           matlab.ui.control.NumericEditField
        OnshoreUrbanEditFieldLabel      matlab.ui.control.Label
        OnshoreUrbanEditField           matlab.ui.control.NumericEditField
        OnshoreHillsEditFieldLabel      matlab.ui.control.Label
        OnshoreHillsEditField           matlab.ui.control.NumericEditField
        OnshoreMountainsEditFieldLabel  matlab.ui.control.Label
        OnshoreMountainsEditField       matlab.ui.control.NumericEditField
        OffshoreSeabedEditFieldLabel    matlab.ui.control.Label
        OffshoreSeabedEditField         matlab.ui.control.NumericEditField
        OffshoreTrenchedEditFieldLabel  matlab.ui.control.Label
        OffshoreTrenchedEditField       matlab.ui.control.NumericEditField
        OffshoreDifficultEditFieldLabel  matlab.ui.control.Label
        OffshoreDifficultEditField      matlab.ui.control.NumericEditField
        OffshoreAverageEditFieldLabel   matlab.ui.control.Label
        OffshoreAverageEditField        matlab.ui.control.NumericEditField
        CrossingsLabel                  matlab.ui.control.Label
        TerraincoverageLabel            matlab.ui.control.Label
        TerraincoverageOKLabel          matlab.ui.control.Label
        MainInputsChangethedefaultinputshereLabel  matlab.ui.control.Label
        MaxPressureOutputEditFieldLabel  matlab.ui.control.Label
        MaxPressureOutputEditField      matlab.ui.control.NumericEditField
        MinPressureInputEditFieldLabel  matlab.ui.control.Label
        MinPressureInputEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp % Description
    end

    properties (Access = public)
        Inputs
        ProjectDuration; % Description
        Length; % Description
        DesiredFlow; % Description
        Flow;
        Util; % Description
        Discount;
        MildSteel; % Description
        StainlessSteel; % Description
        TFlatRural
        TUrban
        THills
        TMountains
        TSandy
        TTrenched
        TDifficult
        TAverage
        COnRoad
        COnPipe
        COnOther
        COffPipe
        COffOther    
        minPres
        maxPres
    end

    methods (Access = private)
    
        function bool = CheckTerrainCoverage(app)
            clear num;
            num(1) = app.TFlatRural;
            num(2) = app.TUrban;
            num(3) = app.THills;
            num(4) = app.TMountains;
            num(5) = app.TSandy;
            num(6) = app.TTrenched;
            num(7) = app.TDifficult;
            num(8) = app.TAverage;
            if sum(num) ~= 100
                bool = false;
                app.TerraincoverageOKLabel.Text = 'Terrain coverage Not OK!';
                app.TerraincoverageOKLabel.FontColor = [0.64, 0.08, 0.18];
            else
                bool =  true;
                app.TerraincoverageOKLabel.Text = 'Terrain coverage OK!';
                app.TerraincoverageOKLabel.FontColor = [0.4706 0.6706 0.1882];
            end
        end
        
        function bool = CheckPipelineComposition(app)
            if (isempty(app.StainlessSteel) &&  app.MildSteel ~= 100) || ((app.MildSteel + app.StainlessSteel) ~=  100)
                bool = false;
                app.PipelinecompositionOKLabel.Text = 'Pipeline composition Not OK!';
                app.PipelinecompositionOKLabel.FontColor = [0.64, 0.08, 0.18];
            else
                bool = true;
                app.PipelinecompositionOKLabel.Text = 'Pipeline composition OK!';
                app.PipelinecompositionOKLabel.FontColor = [0.4706 0.6706 0.1882];
            end
        end
        
        function CheckUpdateButton(app)
            if(CheckTerrainCoverage(app) && CheckPipelineComposition(app))
                app.UpdateButton.Enable = 'on';
            else
                app.UpdateButton.Enable = 'off';
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, changable_inputs)
            app.CallingApp = mainapp;
            T = changable_inputs;       
            app.ProjectDuration = table2array(T(ismember(T.Variable,'Project Duration'),2));
            app.Length = table2array(T(ismember(T.Variable,'Length'),2));
            app.DesiredFlow = table2array(T(ismember(T.Variable,'Flow'),2));
            app.Util = table2array(T(ismember(T.Variable,'Utilization Rate'),2));
            app.Discount = table2array(T(ismember(T.Variable,'Discount Rate'),2));
            app.MildSteel = table2array(T(ismember(T.Variable,'Mild Steel'), 2));
            app.StainlessSteel = table2array(T(ismember(T.Variable,'Stainless Steel'), 2));
            
            app.TFlatRural = table2array(T(ismember(T.Variable,'Terrain - Onshore - Flat rural'),2));
            app.TUrban = table2array(T(ismember(T.Variable,'Terrain - Onshore - Urban'),2));
            app.THills= table2array(T(ismember(T.Variable,'Terrain - Onshore - Hills'),2));
            app.TMountains= table2array(T(ismember(T.Variable,'Terrain - Onshore  - Mountains'),2));
            app.TSandy= table2array(T(ismember(T.Variable,'Terrain - Offshore - Sandy seabed'),2));
            app.TTrenched= table2array(T(ismember(T.Variable,'Terrain - Offshore - Trenched'),2));
            app.TDifficult= table2array(T(ismember(T.Variable,'Terrain - Offshore - Difficult'),2));
            app.TAverage= table2array(T(ismember(T.Variable,'Terrain - Offshore - Average'),2));
            app.COnRoad= table2array(T(ismember(T.Variable,'Crossing - Onshore - Road'),2));
            app.COnPipe= table2array(T(ismember(T.Variable,'Crossing - Onshore - Pipeline'),2));
            app.COnOther= table2array(T(ismember(T.Variable,'Crossing - Onshore - Other'),2));
            app.COffPipe= table2array(T(ismember(T.Variable,'Crossing - Offshore - Pipeline'),2));
            app.COffOther = table2array(T(ismember(T.Variable,'Crossing - Offshore - Other'),2));
            
            app.minPres = table2array(T(ismember(T.Variable,'Minimum Pressure'),2));
            app.maxPres = table2array(T(ismember(T.Variable,'Maximum Pressure'),2));
            
            app.ProjectDurationyearsSlider.Value = app.ProjectDuration;
            app.PipelineLengthkmSlider.Value = app.Length / 1000;
            app.DesignAnnualFlowMtaSlider.Value = app.DesiredFlow;
            app.DiscountRateKnob.Value = app.Discount;
            app.UtilizationRateFlowKnob.Value = app.Util;
            app.OnshoreRoadSpinner.Value = app.COnRoad;
            app.OnshoreOtherSpinner.Value = app.COnOther;
            app.OnshorePipeSpinner.Value = app.COnPipe;
            app.OffshorePipeSpinner.Value = app.COffPipe;
            app.OffshoreOtherSpinner.Value = app.COffOther;
            app.OnshoreRuralEditField.Value = app.TFlatRural;
            app.OnshoreUrbanEditField.Value = app.TUrban;
            app.OnshoreHillsEditField.Value = app.THills;
            app.OnshoreMountainsEditField.Value = app.TMountains;
            app.OffshoreSeabedEditField.Value = app.TSandy;
            app.OffshoreTrenchedEditField.Value = app.TTrenched;
            app.OffshoreDifficultEditField.Value = app.TDifficult;
            app.OffshoreAverageEditField.Value = app.TAverage;         
            app.MaxPressureOutputEditField.Value = app.maxPres;
            app.MinPressureInputEditField.Value = app.minPres;
            
            if(app.StainlessSteel ~= 0)
                app.EnableSteelSettingsSwitch.Value = 'On';
                app.MildSteelEditField.Enable = 'on';
                app.StainlessSteelEditField.Enable = 'on';
                app.MildSteelEditField.Value = app.MildSteel;
                app.StainlessSteelEditField.Value = app.StainlessSteel;
            end
            app.Inputs = changable_inputs;
        end

        % Value changed function: ProjectDurationyearsSlider
        function ProjectDurationyearsSliderValueChanged(app, event)
            app.ProjectDuration = app.ProjectDurationyearsSlider.Value;
            T = app.Inputs;
            T(ismember(T.Variable,'Project Duration'),2) = num2cell(app.ProjectDuration);
            app.Inputs = T;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           app.CallingApp.ChangeParametersButton.Enable = 'on';
           delete(app)      
        end

        % Value changed function: PipelineLengthkmSlider
        function PipelineLengthkmSliderValueChanged(app, event)
           app.Length = app.PipelineLengthkmSlider.Value * 1000;    
            T = app.Inputs;
            T(ismember(T.Variable,'Length'),2) = num2cell(app.Length);
            app.Inputs = T;
        end

        % Value changed function: DesignAnnualFlowMtaSlider
        function DesignAnnualFlowMtaSliderValueChanged(app, event)
            app.DesiredFlow = app.DesignAnnualFlowMtaSlider.Value;
            T = app.Inputs;
            T(ismember(T.Variable,'Flow'),2) = num2cell(app.DesiredFlow);
            app.Inputs = T;
        end

        % Value changed function: DiscountRateKnob
        function DiscountRateKnobValueChanged(app, event)
            app.Discount = app.DiscountRateKnob.Value;
            T = app.Inputs / 100;
            T(ismember(T.Variable,'Discount Rate'),2) = num2cell(app.Discount);
            app.Inputs = T ;
        end

        % Value changed function: UtilizationRateFlowKnob
        function UtilizationRateFlowKnobValueChanged(app, event)
            app.Util = app.UtilizationRateFlowKnob.Value;
            T = app.Inputs ;
            T(ismember(T.Variable,'Utilization Rate'),2) = num2cell(app.Util);
            app.Inputs = T;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            updateparameters(app.CallingApp, app.Inputs);
            app.CallingApp.Label.Text = 'Updated the parameters succesfully!';
            app.CallingApp.Label_2.Text = 'Updated the parameters succesfully!';
            app.CallingApp.ChangeParametersButton.Enable = 'on';
            app.CallingApp.CostsPipeLamp.Color = [0.93, 0.69, 0.13];
            app.CallingApp.CostsElecLamp.Color = [0.93, 0.69, 0.13];
            app.CallingApp.CostsPipeLabel.Text = "Recompute Costs";
            app.CallingApp.CostsElecLabel.Text = "Recompute Costs";
            delete(app)
        end

        % Value changed function: EnableSteelSettingsSwitch
        function EnableSteelSettingsSwitchValueChanged(app, event)
            value = app.EnableSteelSettingsSwitch.Value;
            if strcmp(value, 'On')
                app.MildSteelEditField.Enable = 'on';
                app.StainlessSteelEditField.Enable = 'on';
            else
                app.MildSteelEditField.Enable = 'off';
                app.StainlessSteelEditField.Enable = 'off';
            end
        end

        % Value changed function: MildSteelEditField
        function MildSteelEditFieldValueChanged(app, event)
            value = app.MildSteelEditField.Value;
            app.MildSteel = value; 
            T = app.Inputs;
            T(ismember(T.Variable,'Mild Steel'),2) = num2cell(value);
            app.Inputs = T;
            CheckUpdateButton(app);
        end

        % Value changed function: StainlessSteelEditField
        function StainlessSteelEditFieldValueChanged(app, event)
            value = app.StainlessSteelEditField.Value;
            app.StainlessSteel = value;
            T = app.Inputs;
            T(ismember(T.Variable,'Stainless Steel'),2) = num2cell(value);
            app.Inputs = T;
            CheckUpdateButton(app);
        end

        % Value changed function: OnshoreRoadSpinner
        function OnshoreRoadSpinnerValueChanged(app, event)
            value = app.OnshoreRoadSpinner.Value;
            app.COnRoad = value; 
            T = app.Inputs;
            T(ismember(T.Variable,'Crossing - Onshore - Road'),2) = num2cell(value);
            app.Inputs = T;
        end

        % Value changed function: OnshorePipeSpinner
        function OnshorePipeSpinnerValueChanged(app, event)
            value = app.OnshorePipeSpinner.Value;
            app.COnPipe = value; 
            T = app.Inputs;
            T(ismember(T.Variable,'Crossing - Onshore - Pipeline'),2) = num2cell(value);
            app.Inputs = T;
        end

        % Value changed function: OnshoreOtherSpinner
        function OnshoreOtherSpinnerValueChanged(app, event)
            value = app.OnshoreOtherSpinner.Value;
            app.COnOther = value; 
            T = app.Inputs;
            T(ismember(T.Variable,'Crossing - Onshore - Other'),2) = num2cell(value);
            app.Inputs = T;
        end

        % Value changed function: OffshorePipeSpinner
        function OffshorePipeSpinnerValueChanged(app, event)
            value = app.OffshorePipeSpinner.Value;
            app.COffPipe = value; 
            T = app.Inputs;
            T(ismember(T.Variable,'Crossing - Offshore - Pipeline'),2) = num2cell(value);
            app.Inputs = T;
        end

        % Value changed function: OffshoreOtherSpinner
        function OffshoreOtherSpinnerValueChanged(app, event)
            value = app.OffshoreOtherSpinner.Value;
            app.COffOther = value; 
            T = app.Inputs;
            T(ismember(T.Variable,'Crossing - Offshore - Other'),2) = num2cell(value);
            app.Inputs = T;
        end

        % Value changed function: OnshoreRuralEditField
        function OnshoreRuralEditFieldValueChanged(app, event)
            value = app.OnshoreRuralEditField.Value;
            app.TFlatRural = value; 
            T = app.Inputs;
            T(ismember(T.Variable,'Terrain - Onshore - Flat rural'),2) = num2cell(value);
            app.Inputs = T;
            CheckUpdateButton(app);
        end

        % Value changed function: OnshoreUrbanEditField
        function OnshoreUrbanEditFieldValueChanged(app, event)
            value = app.OnshoreUrbanEditField.Value;
            app.TUrban = value; 
            T = app.Inputs;
            T(ismember(T.Variable,'Terrain - Onshore - Urban'),2) = num2cell(value);
            app.Inputs = T;
            CheckUpdateButton(app);
        end

        % Value changed function: OnshoreHillsEditField
        function OnshoreHillsEditFieldValueChanged(app, event)
            value = app.OnshoreHillsEditField.Value;
            app.THills = value; 
            T = app.Inputs;
            T(ismember(T.Variable,'Terrain - Onshore - Hills'),2) = num2cell(value);
            app.Inputs = T;
            CheckUpdateButton(app);
        end

        % Value changed function: OnshoreMountainsEditField
        function OnshoreMountainsEditFieldValueChanged(app, event)
            value = app.OnshoreMountainsEditField.Value;
            app.TMountains = value; 
            T = app.Inputs;
            T(ismember(T.Variable,'Terrain - Onshore - Mountains'),2) = num2cell(value);
            app.Inputs = T;
            CheckUpdateButton(app);
        end

        % Value changed function: OffshoreSeabedEditField
        function OffshoreSeabedEditFieldValueChanged(app, event)
            value = app.OffshoreSeabedEditField.Value;
            app.TSandy = value; 
            T = app.Inputs;
            T(ismember(T.Variable,'Terrain - Offshore - Sandy seabed'),2) = num2cell(value);
            app.Inputs = T;
            CheckUpdateButton(app);
        end

        % Value changed function: OffshoreTrenchedEditField
        function OffshoreTrenchedEditFieldValueChanged(app, event)
            value = app.OffshoreTrenchedEditField.Value;
            app.TTrenched = value; 
            T = app.Inputs;
            T(ismember(T.Variable,'Terrain - Offshore - Trenched'),2) = num2cell(value);
            app.Inputs = T;
            CheckUpdateButton(app);
        end

        % Value changed function: OffshoreDifficultEditField
        function OffshoreDifficultEditFieldValueChanged(app, event)
            value = app.OffshoreDifficultEditField.Value;
            app.TDifficult = value; 
            T = app.Inputs;
            T(ismember(T.Variable,'Terrain - Offshore - Difficult'),2) = num2cell(value);
            app.Inputs = T;
            CheckUpdateButton(app);
        end

        % Value changed function: OffshoreAverageEditField
        function OffshoreAverageEditFieldValueChanged(app, event)
            value = app.OffshoreAverageEditField.Value;
            app.TAverage = value; 
            T = app.Inputs;
            T(ismember(T.Variable,'Terrain - Offshore - Average'),2) = num2cell(value);
            app.Inputs = T;
            CheckUpdateButton(app);
        end

        % Value changed function: MaxPressureOutputEditField
        function MaxPressureOutputEditFieldValueChanged(app, event)
            value = app.MaxPressureOutputEditField.Value;
            app.maxPres = value;
            T = app.Inputs;
            T(ismember(T.Variable,'Maximum Pressure'),2) = num2cell(app.maxPres);
            app.Inputs = T;
        end

        % Value changed function: MinPressureInputEditField
        function MinPressureInputEditFieldValueChanged(app, event)
            value = app.MinPressureInputEditField.Value;
            app.minPres = value;
            T = app.Inputs;
            T(ismember(T.Variable,'Minimum Pressure'),2) = num2cell(app.minPres);
            app.Inputs = T;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProjectDurationyearsSliderLabel = uilabel(app.UIFigure);
            app.ProjectDurationyearsSliderLabel.Text = 'Project Duration (years)';
            app.ProjectDurationyearsSlider = uislider(app.UIFigure);
            app.ProjectDurationyearsSlider.Limits = [0 40];
            app.ProjectDurationyearsSlider.ValueChangedFcn = createCallbackFcn(app, @ProjectDurationyearsSliderValueChanged, true);
            app.PipelineLengthkmSliderLabel = uilabel(app.UIFigure);
            app.PipelineLengthkmSliderLabel.Text = 'Pipeline Length (km)';
            app.PipelineLengthkmSlider = uislider(app.UIFigure);
            app.PipelineLengthkmSlider.Limits = [80 150];
            app.PipelineLengthkmSlider.ValueChangedFcn = createCallbackFcn(app, @PipelineLengthkmSliderValueChanged, true);
            app.PipelineLengthkmSlider.Value = 80;
            app.DesignAnnualFlowMtaSliderLabel = uilabel(app.UIFigure);
            app.DesignAnnualFlowMtaSliderLabel.Text = 'Design Annual Flow (Mt/a)';
            app.DesignAnnualFlowMtaSlider = uislider(app.UIFigure);
            app.DesignAnnualFlowMtaSlider.Limits = [0 20];
            app.DesignAnnualFlowMtaSlider.ValueChangedFcn = createCallbackFcn(app, @DesignAnnualFlowMtaSliderValueChanged, true);
            app.EnableSteelSettingsSwitchLabel = uilabel(app.UIFigure);
            app.EnableSteelSettingsSwitchLabel.Text = 'Enable Steel Settings';
            app.EnableSteelSettingsSwitch = uiswitch(app.UIFigure, 'toggle');
            app.EnableSteelSettingsSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableSteelSettingsSwitchValueChanged, true);
            app.MildSteelLabel = uilabel(app.UIFigure);
            app.MildSteelLabel.Text = 'Mild Steel (%)';
            app.MildSteelEditField = uieditfield(app.UIFigure, 'numeric');
            app.MildSteelEditField.Limits = [0 100];
            app.MildSteelEditField.ValueChangedFcn = createCallbackFcn(app, @MildSteelEditFieldValueChanged, true);
            app.MildSteelEditField.Enable = 'off';
            app.MildSteelEditField.Value = 100;
            app.StainlessSteelLabel = uilabel(app.UIFigure);
            app.StainlessSteelLabel.Text = 'Stainless Steel (%)';
            app.StainlessSteelEditField = uieditfield(app.UIFigure, 'numeric');
            app.StainlessSteelEditField.Limits = [0 100];
            app.StainlessSteelEditField.ValueChangedFcn = createCallbackFcn(app, @StainlessSteelEditFieldValueChanged, true);
            app.StainlessSteelEditField.Enable = 'off';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.DiscountRateKnobLabel = uilabel(app.UIFigure);
            app.DiscountRateKnobLabel.Text = 'Discount Rate';
            app.DiscountRateKnob = uiknob(app.UIFigure, 'continuous');
            app.DiscountRateKnob.Limits = [0 20];
            app.DiscountRateKnob.ValueChangedFcn = createCallbackFcn(app, @DiscountRateKnobValueChanged, true);
            app.DiscountRateKnob.Value = 7;
            app.UtilizationRateFlowLabel = uilabel(app.UIFigure);
            app.UtilizationRateFlowLabel.Text = 'Utilization Rate Flow (%)';
            app.UtilizationRateFlowKnob = uiknob(app.UIFigure, 'continuous');
            app.UtilizationRateFlowKnob.ValueChangedFcn = createCallbackFcn(app, @UtilizationRateFlowKnobValueChanged, true);
            app.UtilizationRateFlowKnob.Value = 70;
            app.PipelinecompositionOKLabel = uilabel(app.UIFigure);
            app.PipelinecompositionOKLabel.Text = 'Pipeline composition OK!';
            app.OnshoreRoadSpinnerLabel = uilabel(app.UIFigure);
            app.OnshoreRoadSpinnerLabel.Text = 'Onshore -Road';
            app.OnshoreRoadSpinner = uispinner(app.UIFigure);
            app.OnshoreRoadSpinner.Limits = [0 Inf];
            app.OnshoreRoadSpinner.ValueChangedFcn = createCallbackFcn(app, @OnshoreRoadSpinnerValueChanged, true);
            app.OnshorePipeSpinnerLabel = uilabel(app.UIFigure);
            app.OnshorePipeSpinnerLabel.Text = 'Onshore - Pipe';
            app.OnshorePipeSpinner = uispinner(app.UIFigure);
            app.OnshorePipeSpinner.Limits = [0 Inf];
            app.OnshorePipeSpinner.ValueChangedFcn = createCallbackFcn(app, @OnshorePipeSpinnerValueChanged, true);
            app.OnshoreOtherSpinnerLabel = uilabel(app.UIFigure);
            app.OnshoreOtherSpinnerLabel.Text = 'Onshore - Other';
            app.OnshoreOtherSpinner = uispinner(app.UIFigure);
            app.OnshoreOtherSpinner.Limits = [0 Inf];
            app.OnshoreOtherSpinner.ValueChangedFcn = createCallbackFcn(app, @OnshoreOtherSpinnerValueChanged, true);
            app.OffshorePipeSpinnerLabel = uilabel(app.UIFigure);
            app.OffshorePipeSpinnerLabel.Text = 'Offshore - Pipe';
            app.OffshorePipeSpinner = uispinner(app.UIFigure);
            app.OffshorePipeSpinner.Limits = [0 Inf];
            app.OffshorePipeSpinner.ValueChangedFcn = createCallbackFcn(app, @OffshorePipeSpinnerValueChanged, true);
            app.OffshoreOtherSpinnerLabel = uilabel(app.UIFigure);
            app.OffshoreOtherSpinnerLabel.Text = 'Offshore - Other';
            app.OffshoreOtherSpinner = uispinner(app.UIFigure);
            app.OffshoreOtherSpinner.Limits = [0 Inf];
            app.OffshoreOtherSpinner.ValueChangedFcn = createCallbackFcn(app, @OffshoreOtherSpinnerValueChanged, true);
            app.OnshoreRuralEditFieldLabel = uilabel(app.UIFigure);
            app.OnshoreRuralEditFieldLabel.Text = 'Onshore - Rural';
            app.OnshoreRuralEditField = uieditfield(app.UIFigure, 'numeric');
            app.OnshoreRuralEditField.Limits = [0 100];
            app.OnshoreRuralEditField.ValueChangedFcn = createCallbackFcn(app, @OnshoreRuralEditFieldValueChanged, true);
            app.OnshoreUrbanEditFieldLabel = uilabel(app.UIFigure);
            app.OnshoreUrbanEditFieldLabel.Text = 'Onshore - Urban';
            app.OnshoreUrbanEditField = uieditfield(app.UIFigure, 'numeric');
            app.OnshoreUrbanEditField.Limits = [0 100];
            app.OnshoreUrbanEditField.ValueChangedFcn = createCallbackFcn(app, @OnshoreUrbanEditFieldValueChanged, true);
            app.OnshoreHillsEditFieldLabel = uilabel(app.UIFigure);
            app.OnshoreHillsEditFieldLabel.Text = 'Onshore - Hills';
            app.OnshoreHillsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OnshoreHillsEditField.Limits = [0 100];
            app.OnshoreHillsEditField.ValueChangedFcn = createCallbackFcn(app, @OnshoreHillsEditFieldValueChanged, true);
            app.OnshoreMountainsEditFieldLabel = uilabel(app.UIFigure);
            app.OnshoreMountainsEditFieldLabel.Text = 'Onshore - Mountains';
            app.OnshoreMountainsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OnshoreMountainsEditField.Limits = [0 100];
            app.OnshoreMountainsEditField.ValueChangedFcn = createCallbackFcn(app, @OnshoreMountainsEditFieldValueChanged, true);
            app.OffshoreSeabedEditFieldLabel = uilabel(app.UIFigure);
            app.OffshoreSeabedEditFieldLabel.Text = 'Offshore - Seabed';
            app.OffshoreSeabedEditField = uieditfield(app.UIFigure, 'numeric');
            app.OffshoreSeabedEditField.Limits = [0 100];
            app.OffshoreSeabedEditField.ValueChangedFcn = createCallbackFcn(app, @OffshoreSeabedEditFieldValueChanged, true);
            app.OffshoreSeabedEditField.Value = 100;
            app.OffshoreTrenchedEditFieldLabel = uilabel(app.UIFigure);
            app.OffshoreTrenchedEditFieldLabel.Text = 'Offshore - Trenched';
            app.OffshoreTrenchedEditField = uieditfield(app.UIFigure, 'numeric');
            app.OffshoreTrenchedEditField.Limits = [0 100];
            app.OffshoreTrenchedEditField.ValueChangedFcn = createCallbackFcn(app, @OffshoreTrenchedEditFieldValueChanged, true);
            app.OffshoreDifficultEditFieldLabel = uilabel(app.UIFigure);
            app.OffshoreDifficultEditFieldLabel.Text = 'Offshore - Difficult';
            app.OffshoreDifficultEditField = uieditfield(app.UIFigure, 'numeric');
            app.OffshoreDifficultEditField.Limits = [0 100];
            app.OffshoreDifficultEditField.ValueChangedFcn = createCallbackFcn(app, @OffshoreDifficultEditFieldValueChanged, true);
            app.OffshoreAverageEditFieldLabel = uilabel(app.UIFigure);
            app.OffshoreAverageEditFieldLabel.Text = 'Offshore - Average';
            app.OffshoreAverageEditField = uieditfield(app.UIFigure, 'numeric');
            app.OffshoreAverageEditField.Limits = [0 100];
            app.OffshoreAverageEditField.ValueChangedFcn = createCallbackFcn(app, @OffshoreAverageEditFieldValueChanged, true);
            app.CrossingsLabel = uilabel(app.UIFigure);
            app.CrossingsLabel.Text = 'Crossings (#)';
            app.TerraincoverageLabel = uilabel(app.UIFigure);
            app.TerraincoverageLabel.Text = 'Terrain (%) coverage';
            app.TerraincoverageOKLabel = uilabel(app.UIFigure);
            app.TerraincoverageOKLabel.Text = 'Terrain coverage OK!';
            app.MainInputsChangethedefaultinputshereLabel = uilabel(app.UIFigure);
            app.MainInputsChangethedefaultinputshereLabel.Text = 'Main Inputs - Change the default inputs here';
            app.MaxPressureOutputEditFieldLabel = uilabel(app.UIFigure);
            app.MaxPressureOutputEditFieldLabel.Text = 'Max Pressure Output';
            app.MaxPressureOutputEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxPressureOutputEditField.Limits = [0 380];
            app.MaxPressureOutputEditField.ValueChangedFcn = createCallbackFcn(app, @MaxPressureOutputEditFieldValueChanged, true);
            app.MaxPressureOutputEditField.Value = 110;
            app.MinPressureInputEditFieldLabel = uilabel(app.UIFigure);
            app.MinPressureInputEditFieldLabel.Text = 'Min Pressure Input';
            app.MinPressureInputEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinPressureInputEditField.Limits = [0 160];
            app.MinPressureInputEditField.ValueChangedFcn = createCallbackFcn(app, @MinPressureInputEditFieldValueChanged, true);
            app.MinPressureInputEditField.Value = 20;
        end
    end

    methods (Access = public)

        % Construct app
        function app = inputapp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end