classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImportDataSetButton         matlab.ui.control.Button
        ChangeParametersButton      matlab.ui.control.Button
        PleaseuploaddatasetLabel    matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        ExtraPipelineTransportationCostModulesPanel  matlab.ui.container.Panel
        PipeMod                     matlab.ui.control.ListBox
        UserInputsLabel             matlab.ui.control.Label
        ExtraElectricTransportationCostModulesPanel  matlab.ui.container.Panel
        ElecMod                     matlab.ui.control.ListBox
        ComputeCostsLabel           matlab.ui.control.Label
        ComputePipelineCostsButton  matlab.ui.control.Button
        ComputeElectricCostsButton  matlab.ui.control.Button
        ResultsLabel                matlab.ui.control.Label
        CostsPipeLamp               matlab.ui.control.Lamp
        CostsElecLamp               matlab.ui.control.Lamp
        CostsPipeLabel              matlab.ui.control.Label
        CostsElecLabel              matlab.ui.control.Label
        ConsiderConstructionofnewPipelineandCableSwitchLabel  matlab.ui.control.Label
        NewPipe                     matlab.ui.control.Switch
        Label_2                     matlab.ui.control.Label
        UIAxesPipe                  matlab.ui.control.UIAxes
        UIAxesPipe2                 matlab.ui.control.UIAxes
        UIAxesElec                  matlab.ui.control.UIAxes
        UIAxesElec2                 matlab.ui.control.UIAxes
        UITable                     matlab.ui.control.Table
        UITable2                    matlab.ui.control.Table
        UITableElec                 matlab.ui.control.Table
        UITable2Elec_2              matlab.ui.control.Table
    end

    
    properties (Access = public)
        Inputs % Description
        ProjectDuration = 20; % Description
        Length = 50; % Description
        DesiredFlow = 5; % Description
        Flow = 0;
        Util = 70; % Description
        Discount = 7;
        pressureDrop = 0;
        MildSteel = 100; % Description
        StainlessSteel = 0; % Description
        TFlatRural = 0;
        TUrban = 0;
        THills = 0;
        TMountains = 0;
        TSandy = 0;
        TTrenched = 0;
        TDifficult = 0;
        TAverage = 0;
        COnRoad = 0;
        COnPipe = 0;
        COnOther = 0;
        COffPipe = 0;
        COffOther = 0;
        POnshore = 0;
        POffshore = 0;
        minPressure = 0;
        maxPressure = 0;
        Density = 138.41; %Liquid CO2 -> Change for H2.
        Diameter = 0;
        Friction = 0;
        WallThickness = 0;
        ConsiderNewPipe = true;
        HoopStress = 0;
        Trains = 0;
        TotalEnergy = 0;
        CompressorCapacity = 0;
        DefaultMinPressure = 0;
        compRequired = 0;
        Energy = 0;
    end
    
    properties (Access = private)
        InputApp % Description
    end
    
    methods (Access = public)
        
        function updateparameters(app, changable_inputs)
            T = changable_inputs;
            app.Inputs = T;
            app.ProjectDuration = table2array(T(ismember(T.Variable,'Project Duration'),2));
            app.Length = table2array(T(ismember(T.Variable,'Length'),2));
            app.DesiredFlow = table2array(T(ismember(T.Variable,'Flow'),2));
            app.Util = table2array(T(ismember(T.Variable,'Utilization Rate'),2));
            app.Discount = table2array(T(ismember(T.Variable,'Discount Rate'),2));
            app.MildSteel = table2array(T(ismember(T.Variable,'Mild Steel'),2));
            app.StainlessSteel  = table2array(T(ismember(T.Variable,'Stainless Steel'),2));
            app.Flow = app.DesiredFlow * app.Util / 100;
            
            app.TFlatRural = table2array(T(ismember(T.Variable,'Terrain - Onshore - Flat rural'),2));
            app.TUrban = table2array(T(ismember(T.Variable,'Terrain - Onshore - Urban'),2));
            app.THills= table2array(T(ismember(T.Variable,'Terrain - Onshore - Hills'),2));
            app.TMountains= table2array(T(ismember(T.Variable,'Terrain - Onshore  - Mountains'),2));
            app.TSandy= table2array(T(ismember(T.Variable,'Terrain - Offshore - Sandy seabed'),2));
            app.TTrenched= table2array(T(ismember(T.Variable,'Terrain - Offshore - Trenched'),2));
            app.TDifficult= table2array(T(ismember(T.Variable,'Terrain - Offshore - Difficult'),2));
            app.TAverage= table2array(T(ismember(T.Variable,'Terrain - Offshore - Average'),2));
            app.COnRoad= table2array(T(ismember(T.Variable,'Crossing - Onshore - Road'),2));
            app.COnPipe= table2array(T(ismember(T.Variable,'Crossing - Onshore - Pipeline'),2));
            app.COnOther= table2array(T(ismember(T.Variable,'Crossing - Onshore - Other'),2));
            app.COffPipe= table2array(T(ismember(T.Variable,'Crossing - Offshore - Pipeline'),2));
            app.COffOther = table2array(T(ismember(T.Variable,'Crossing - Offshore - Other'),2));
            app.POnshore = app.TFlatRural +  app.TUrban  +  app.THills + app.TMountains;
            app.POffshore =  app.TSandy + app.TTrenched + app.TDifficult + app.TAverage;
            
                        
            app.minPressure = table2array(T(ismember(T.Variable,'Minimum Pressure'),2));
            app.maxPressure = table2array(T(ismember(T.Variable,'Maximum Pressure'),2));
        end
        function table =VLookDiameter(app, Ws)
            data_mm = [6; 12; 18; 22; 24; 28; 32; 36; 44; 48];
            %Calculate Diameter
            data_m = 1e-3 * data_mm;
            data_inch = 25.4* data_m;
            Flow_Column = round(0.0315 * app.Density*pi*data_inch.^2.*sqrt( (2*10.*data_inch) ./ (app.Density*(app.Length/1000)*0.005)),1)
            %Calculate Wall
            data_wall = ceil(100 * 0.5 * app.maxPressure .* data_inch / Ws);
            Hoop_Column = ceil((app.maxPressure / 10) .* data_inch./ (2*(data_wall/1000)))
            %Create VLook Table
            F = zeros(length(data_mm),1);
            F(2:length(F),1) = Flow_Column(1:(length(F)-1),1);
            table = [F, data_mm, data_inch*1000, data_wall, Hoop_Column];
        end
    
        function costs_pipeline = calculatePipelineCosts(app, T)
            costs_newPipe = zeros(5,1);
            costs_existingPipe = zeros(5,1);
            costs_compression = zeros(5,1);
            costs_elektrolyse = zeros(5,1);
            costs_ = computeNewPipelineCosts(app, T)
            if (strcmp(app.ConsiderNewPipe,'Construction'))
                costs_newPipe = costs_;
            else
                costs_existingPipe = [ 0 ; 0; 0;costs_(4); costs_(5)];
            end
            moduleComp = ismember(app.PipeMod.Value, 'Consider Compression Costs');
            if (sum(moduleComp)~=0)
                    costs_compression = costsCompression(app, T);
  
            end
            elekComp = ismember(app.PipeMod.Value, 'Consider Electrolysis Costs');
            if (sum(elekComp)~= 0)
                costs_elektrolyse = costsElektrolyse(app, T);
            end
            %Incorporating the Discount Rate
            costs_pipeline = [costs_newPipe , costs_existingPipe , costs_compression, costs_elektrolyse];
            TotalCapex = sum(sum(costs_pipeline(1:3,:)));
            TotalOpex = sum(sum(costs_pipeline(4:5,:)));
            EuroPerTonnesPipe = ((app.Discount * TotalCapex / 100) / (1 - (1+ app.Discount/100)^(-app.ProjectDuration)) + (TotalOpex/app.ProjectDuration)) / app.Flow; 
            app.CostsPipeLabel.Text = "Costs computed successfully.";
            app.CostsPipeLamp.Color = [0.4706 0.6706 0.1882];
        end
        
        function costs_newPipeline = computeNewPipelineCosts(app, T)
            safetyFactor = 2;
            maxMPa = 483;
            if(app.StainlessSteel ~= 0)
                maxMPa = 550;
            end
            %Find Diameter and 
            wall_stress = maxMPa / safetyFactor;
            Data = VLookDiameter(app, wall_stress );
            app.Flow = app.DesiredFlow * app.Util / 100;
            elementToFind = Data(sum(Data(:,1) < app.DesiredFlow),1);
            columnForDiam = 3;
            columnForWall = 4;
            [tf, rowWithElement] = ismember(elementToFind, Data(:,1));
            
            app.Diameter = Data(rowWithElement, columnForDiam) / 1000;
            app.WallThickness = Data(rowWithElement, columnForWall) / 1000;
            
            %Check if Hoopstress is safe. Else return a Warning.
            app.HoopStress = ((app.maxPressure / 10) * (0.5 * app.Diameter )) / (app.WallThickness);
            if(app.HoopStress > ( maxMPa / safetyFactor))
                if(isempty(app.Label.Text))
                    app.Label_2.Text = "Warning: Hoop Stress Unsafe";
                else
                    app.Label_2.Text = strcat(app.Label.Text, " / " , "Warning: Hoop Stress Unsafe");
                end
            end
            
            %Check if Pressuredrop is reasonable
            flowRate = (1e9 * app.DesiredFlow / (365 * 24 * 60 * 60)) / app.Density; %m3/s
            flowRatePipe = (4 * flowRate) / ( pi * (app.Diameter) ^ 2); %m/s
            ReynoldsNumber =  1e6 * flowRatePipe * ( app.Diameter ) / (0.11);
            if ( ReynoldsNumber < 2300)
                app.Friction = 64 / ReynoldsNumber;
            else
                app.Friction = 0.3164 / (ReynoldsNumber^0.25);
            end
            
            app.pressureDrop = app.Length * app.Friction * 0.5 * app.Density * (flowRatePipe^2 / app.Diameter) * 1e-5;
            maxDropRate =  10 / 100;
            dropLimit = app.Length * maxDropRate / 1000;
            if app.pressureDrop > dropLimit
                if(isempty(app.Label.Text))
                    app.Label_2.Text = "Warning: High Pressure Loss";
                else
                    app.Label_2.Text = strcat(app.Label.Text, " / " , "Warning: High Pressure Loss");
                end
            end
            
            % Compute if Compression is required.
            alpha = max([app.DefaultMinPressure; app.minPressure])-(app.maxPressure - app.pressureDrop);
            app.compRequired = max([alpha;0]); 
            if(app.compRequired > 0 && sum(ismember(app.PipeMod.Value, 'Consider Compression Costs'))==0)
                app.Label_2.Text = "Compression Required, please select this module!";
            elseif (app.compRequired == 0 &&  sum(ismember(app.PipeMod.Value, 'Consider Compression Costs'))> 0 )
                app.Label_2.Text = "Compression NOT Required, please deselect this module!"; 
            end
            
            %Compute Required Material and Material Costs (in millions)
            MildSteelPrice = table2array(T(ismember(T.Variable,'Mild Steel Price'),2));
            StainlessSteelPrice = table2array(T(ismember(T.Variable,'Stainless Steel Price'),2));
            CostPerTonne = app.StainlessSteel / 100 * StainlessSteelPrice + app.MildSteel / 100 * MildSteelPrice;
            
            QuantityRequired = 7850 * pi * app.Length / 1000 * app.WallThickness * (app.Diameter  - app.WallThickness);
            TotalMaterialCosts = QuantityRequired * CostPerTonne / 1e+06 ;
             
            %Costs for onshore pipes
            ScaleFactorLabour = table2array(T(ismember(T.Variable,'Scale Factor Labour Costs'),2));
            LabourCosts =  ScaleFactorLabour * TotalMaterialCosts  ;
            
            ScaleFactorOther = table2array(T(ismember(T.Variable,'Scale Factor Other Costs'),2));
            OtherCosts = ScaleFactorOther * TotalMaterialCosts ;
            
            %Compute Costs for Surroundings (in millions)
            CrossingCosts = app.COnRoad * 5 + app.COnPipe * 5 + app.COnOther * 5 + app.COffPipe * 6 + app.COffOther * 11;
            
            %Compute Additional Costs (in millions) only for offshore pipes
            AdditionalCosts =(16 + 0.64 * app.Length /1000) * app.POffshore / 100  ;
            
            %Scale appropriate to terrain
            OnshoreTerrain = [app.TFlatRural app.TUrban app.THills app.TMountains];
            OnshoreFactors = [1 1.3 1.3 1.5];
            if(sum(OnshoreTerrain)~= 0)
                EffectiveScalingFactorOnshore = sum(OnshoreTerrain.*OnshoreFactors) / sum(OnshoreTerrain);
            else
                EffectiveScalingFactorOnshore = 1;
            end
            
            OffshoreTerrain = [app.TSandy app.TTrenched app.TDifficult app.TAverage];
            OffshoreFactors = [1 1.5 3 1.2];
            if(sum(OffshoreTerrain) ~=0)
                EffectiveScalingFactorOffshore = sum(OffshoreTerrain.*OffshoreFactors) / sum(OffshoreTerrain);
            else
                EffectiveScalingFactorOffshore = 1;
            end
            Costs = [TotalMaterialCosts; LabourCosts; OtherCosts; AdditionalCosts];
            ScaledCosts = (EffectiveScalingFactorOnshore*app.POnshore/100 + EffectiveScalingFactorOffshore*app.POffshore/100).*Costs;
%             ScaledBase = (TotalMaterialCosts + LabourCosts + OtherCosts) * EffectiveScalingFactorOnshore ;
%             ScaledOffshore = AdditionalCosts * EffectiveScalingFactorOffshore  
            OPEXFixed = app.ProjectDuration * sum(ScaledCosts) * table2array(T(ismember(T.Variable,'O & M Cost fixed'),2)) / 100;
            OPEXVar = app.ProjectDuration * app.DesiredFlow * table2array(T(ismember(T.Variable,'O & M Cost variable'),2)) / 100;
            costs_newPipeline = [sum(ScaledCosts); CrossingCosts ;0 ; OPEXFixed ; OPEXVar];
           
        end
    
        function costs_compression = costsCompression(app, T)
            flow_tonnes_per_second = ( app.DesiredFlow * 1e6 ) / ( 365 * 24 * 60 * 60);
            flow_per_train = (1000 * flow_tonnes_per_second ) / app.Trains;
            minCapt = table2array(T(ismember(T.Variable,'CO2CaptMin'),2));
            maxCapt = table2array(T(ismember(T.Variable,'CO2CaptMax'),2));
            
            if(flow_per_train < minCapt)
                Captured = minCapt;
            elseif( flow_per_train > maxCapt)
                Captured = maxCapt;
            else
                Captured = flow_per_train;
            end
            
            alpha = max([app.DefaultMinPressure; app.minPressure])-(app.maxPressure - app.pressureDrop);
            app.compRequired = max([alpha;0]); 
            if app.compRequired == 0
                costs_compression = zeros(5,1);
                return
            end
            MWTable =  [
                        2	3	32414
                        3	8	17436
                        8	12	8395
                        12	25	4714
                        25	40	2357
                        40	60	1380
                        60	100	760
                        100	200	600
                        200	300	400];
            elementToFind = MWTable(sum(MWTable(:,1) < app.compRequired),1);
            columnForEnergy = 3;
            [tf, rowWithElement] = ismember(elementToFind, MWTable(:,1));
            app.Energy = MWTable(rowWithElement, columnForEnergy) * app.minPressure * (app.DesiredFlow* 1e9 / (365*24*3600)) / 1e6;
            app.TotalEnergy = app.Energy * 1000 * 3600 * table2array(T(ismember(T.Variable,'Compressor Load'),2));
            
            OPEX_Var = app.TotalEnergy * table2array(T(ismember(T.Variable,'SEICost'),2)) / 1e6;
            % Calculate required Energy 
%             EnergySpecific = table2array(T(ismember(T.Variable,'VarAEnergy'),2)) * log((app.maxPressure * 1e6)/(app.minPressure * 1e6));
%             app.TotalEnergy = EnergySpecific * Captured; %per second
%             app.CompressorCapacity = app.TotalEnergy / 1000;
            
            I0 = table2array(T(ismember(T.Variable,'I0'),2));
            W0 = table2array(T(ismember(T.Variable,'W0'),2));
            y = table2array(T(ismember(T.Variable,'Scaling factor'),2));
            me = table2array(T(ismember(T.Variable,'me'),2));
            CAPEX_Compressor = I0*(app.compRequired / W0)^y*app.Trains^me;
            OPEX_Fixed_Compressor = table2array(T(ismember(T.Variable,'OpexCapexRatio'),2)) / 100 * CAPEX_Compressor;

            % Calulate Compressor Costs
%             I0 = table2array(T(ismember(T.Variable,'I0'),2));
%             W0 = table2array(T(ismember(T.Variable,'W0'),2));
%             y = table2array(T(ismember(T.Variable,'Scaling factor'),2));
%             me = table2array(T(ismember(T.Variable,'me'),2));
%             CAPEX_Compressor = I0*(app.CompressorCapacity / W0)^y*app.Trains^me;
%             OPEX_Fixed_Compressor = table2array(T(ismember(T.Variable,'OpexCapexRatio'),2)) / 100 * CAPEX_Compressor;
%             
            %Calculate Variable OPEX Costs
%             r = (app.Flow / app.DesiredFlow) * app.Trains;
%             SpecificCosts = 0.001 * table2array(T(ismember(T.Variable,'Electricity Costs'),2)) / 3600;
%             Energy = app.TotalEnergy * 3600 * table2array(T(ismember(T.Variable,'Compressor Load'),2)); %years
%             OPEX_Var = r * Energy * SpecificCosts / 1e6; 
            costs_compression = [CAPEX_Compressor ;0 ;0 ;OPEX_Fixed_Compressor * app.ProjectDuration ;OPEX_Var * app.ProjectDuration];
        end

        function costs_elektrolyse = costsElektrolyse(app, T)
            costs_elektrolyse = zeros(5,1);
            ElectricityRequired = (app.DesiredFlow * table2array(T(ismember(T.Variable,'Electrolyse Efficiency'),2)) ) / 1000;
            Electricity_Total = ElectricityRequired / table2array(T(ismember(T.Variable,'Effectiveness'),2));
            ElectrolyserSize = Electricity_Total / (table2array(T(ismember(T.Variable,'Electrolyser Load'),2)) * table2array(T(ismember(T.Variable,'Hours below benchmark'),2))/100);
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: ChangeParametersButton
        function ChangeParametersButtonPushed(app, event)
            app.ChangeParametersButton.Enable = 'off';
            app.Label.Text = "";
            app.InputApp = inputapp(app, app.Inputs);
        end

        % Button pushed function: ImportDataSetButton
        function ImportDataSetButtonPushed(app, event)
            try
                [file, path] = uigetfile('*.xlsx');
                app.PleaseuploaddatasetLabel.Text = 'Inputs uploaded successfully!';
                T = readtable(file);
                T = T(1:end,2:3);
                app.ProjectDuration = table2array(T(ismember(T.Variable,'Project Duration'),2));
                app.Length = table2array(T(ismember(T.Variable,'Length'),2));
                app.Flow = table2array(T(ismember(T.Variable,'Flow'),2));
                app.Util = table2array(T(ismember(T.Variable,'Utilization Rate'),2));
                app.Discount = table2array(T(ismember(T.Variable,'Discount Rate'),2));
                app.MildSteel = table2array(T(ismember(T.Variable,'Mild Steel'),2));
                app.StainlessSteel = table2array(T(ismember(T.Variable,'Stainless Steel'),2));
                
                app.TFlatRural = table2array(T(ismember(T.Variable,'Terrain - Onshore - Flat rural'),2));
                app.TUrban = table2array(T(ismember(T.Variable,'Terrain - Onshore - Urban'),2));
                app.THills= table2array(T(ismember(T.Variable,'Terrain - Onshore - Hills'),2));
                app.TMountains= table2array(T(ismember(T.Variable,'Terrain - Onshore  - Mountains'),2));
                app.TSandy= table2array(T(ismember(T.Variable,'Terrain - Offshore - Sandy seabed'),2));
                app.TTrenched= table2array(T(ismember(T.Variable,'Terrain - Offshore - Trenched'),2));
                app.TDifficult= table2array(T(ismember(T.Variable,'Terrain - Offshore - Difficult'),2));
                app.TAverage= table2array(T(ismember(T.Variable,'Terrain - Offshore - Average'),2));
                app.COnRoad= table2array(T(ismember(T.Variable,'Crossing - Onshore - Road'),2));
                app.COnPipe= table2array(T(ismember(T.Variable,'Crossing - Onshore - Pipeline'),2));
                app.COnOther= table2array(T(ismember(T.Variable,'Crossing - Onshore - Other'),2));
                app.COffPipe= table2array(T(ismember(T.Variable,'Crossing - Offshore - Pipeline'),2));
                app.COffOther = table2array(T(ismember(T.Variable,'Crossing - Offshore - Other'),2));
                app.POnshore = app.TFlatRural +  app.TUrban  +  app.THills + app.TMountains;
                app.POffshore =  app.TSandy + app.TTrenched + app.TDifficult + app.TAverage;
                
                app.DefaultMinPressure = table2array(T(ismember(T.Variable,'Minimum Pressure'),2));
                app.minPressure = table2array(T(ismember(T.Variable,'Minimum Pressure'),2));
                app.maxPressure = table2array(T(ismember(T.Variable,'Maximum Pressure'),2));
                
                app.Trains = table2array(T(ismember(T.Variable,'Number of Trains'),2));
                
                app.Inputs = T;
                app.ChangeParametersButton.Enable = 'on';
                app.ComputePipelineCostsButton.Enable = 'on';
                app.ComputeElectricCostsButton.Enable = 'on';
            catch
                app.PleaseuploaddatasetLabel.Text = 'Inputs uploaded unsuccessfully!';
                return;
            end
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.InputApp)
            delete(app)
        end

        % Value changed function: PipeMod
        function PipeModValueChanged(app, event)
            value = app.PipeMod.Value;
            app.CostsPipeLamp.Color = [0.93, 0.69, 0.13];
            app.CostsPipeLabel.Text = "Recompute costs";
        end

        % Value changed function: ElecMod
        function ElecModValueChanged(app, event)
            value = app.ElecMod.Value;
            app.CostsElecLamp.Color = [0.93, 0.69, 0.13];
            app.CostsElecLabel.Text = "Recompute costs";
        end

        % Button pushed function: ComputePipelineCostsButton
        function ComputePipelineCostsButtonPushed(app, event)
            app.Label_2.Text = "";
            costs = calculatePipelineCosts(app, app.Inputs);
            CapexCosts = [costs(1,1); costs(2,1); costs(1,3); costs(1,4)];
            nanC = [CapexCosts'; nan(1,4)];
            bar(app.UIAxesPipe, nanC, 'Stacked');
            set(app.UIAxesPipe, 'xtick', 1);
            xlim(app.UIAxesPipe, [0.25 1.75])
            legend(app.UIAxesPipe, {'M/L/O', 'Environment', 'Compressor', 'Electrolysis'}, 'Location', 'southeast')
            text(app.UIAxesPipe,1,sum(CapexCosts),num2str(sum(CapexCosts)),'vert','bottom','horiz','center');
            xticklabels(app.UIAxesPipe, {'Capex'})
            
            OpexVar = [costs(5,1); costs(5,2); costs(5,3); costs(5,4)];
            OpexFix = [costs(4,1); costs(4,2); costs(4,3); costs(4,4)];
            bar(app.UIAxesPipe2, [OpexFix'; OpexVar'], 'Stacked');
            legend(app.UIAxesPipe2, {'Pipe Constr.', 'Pipe No Constr.', 'Compressor', 'Elektrolysis'}, 'Location', 'southeast')
            xticklabels(app.UIAxesPipe2, {'OpexFix', 'OpexVar'})
            text(app.UIAxesPipe2,1:2,[sum(OpexFix'); sum(OpexVar')],num2str([sum(OpexFix'); sum(OpexVar')]),'vert','bottom','horiz','center');
            
            
            TypeC={'M/L/O'; 'Environment'; 'Compressor'; 'Electrolysis';'';''; 'Total' };
            CostsC = [num2cell(CapexCosts);  0; 0 ;num2cell(sum(CapexCosts))];
            TypeO={'Pipeline Fix'; 'Pipeline Var'; 'Compressor Fix'; 'Compressor Var';'Elektrolysis Fix'; 'Elektrolysis Var'; 'Total'};
            if(app.NewPipe.Value == "Construction")
                CostsO = [ costs(4,1); costs(5,1); costs(4,3); costs(5,3);costs(4,4) ; costs(5,4); sum([OpexVar; OpexFix]) ];
            else
                CostsO = [ costs(4,2); costs(5,2); costs(4,3); costs(5,3); costs(4,4) ; costs(5,4);sum([OpexVar; OpexFix]) ];
            end
            Data = table(TypeC, CostsC, TypeO, num2cell(CostsO));
            app.UITable.Data = Data; 
            TotalCapex =sum(CapexCosts);
            TotalOpex = sum([OpexVar./app.ProjectDuration; OpexFix./app.ProjectDuration]);
            EuroPerTonnesPipe = ((app.Discount * TotalCapex / 100) / (1 - (1+ app.Discount/100)^(-app.ProjectDuration)) + TotalOpex) / app.Flow;
            CapexOpexRatio = ((app.Discount * TotalCapex / 100) / (1 - (1+ app.Discount/100)^(-app.ProjectDuration))) / TotalOpex;
            
            ResultType = {'Euro Per Tonnes'; 'Capex : Opex Ratio'};
            ResultVal = [EuroPerTonnesPipe; CapexOpexRatio];
            Data = table(ResultType, ResultVal);
            app.UITable2.Data = Data;
        end

        % Value changed function: NewPipe
        function NewPipeValueChanged(app, event)
            value = app.NewPipe.Value;
            app.ConsiderNewPipe = value;
            app.CostsPipeLamp.Color = [0.93, 0.69, 0.13]; %[0.4706 0.6706 0.1882];
            app.CostsElecLamp.Color = [0.93, 0.69, 0.13]; 
            app.CostsPipeLabel.Text = "Recompute Costs";
            app.CostsElecLabel.Text = "Recompute Costs";
        end

        % Size changed function: 
        % ExtraPipelineTransportationCostModulesPanel
        function ExtraPipelineTransportationCostModulesPanelSizeChanged(app, event)
            position = app.ExtraPipelineTransportationCostModulesPanel.Position;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImportDataSetButton = uibutton(app.UIFigure, 'push');
            app.ImportDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataSetButtonPushed, true);
            app.ImportDataSetButton.Text = 'Import Data Set';
            app.ChangeParametersButton = uibutton(app.UIFigure, 'push');
            app.ChangeParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeParametersButtonPushed, true);
            app.ChangeParametersButton.Enable = 'off';
            app.ChangeParametersButton.Text = 'Change Parameters';
            app.PleaseuploaddatasetLabel = uilabel(app.UIFigure);
            app.PleaseuploaddatasetLabel.Text = 'Please upload data set';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ExtraPipelineTransportationCostModulesPanel = uipanel(app.UIFigure);
            app.ExtraPipelineTransportationCostModulesPanel.Title = 'Extra Pipeline Transportation Cost Modules';
            app.ExtraPipelineTransportationCostModulesPanel.SizeChangedFcn = createCallbackFcn(app, @ExtraPipelineTransportationCostModulesPanelSizeChanged, true);
            app.PipeMod = uilistbox(app.ExtraPipelineTransportationCostModulesPanel);
            app.PipeMod.Items = {'Consider Compression Costs', 'Consider Electrolysis Costs', 'No Extra Modules'};
            app.PipeMod.Multiselect = 'on';
            app.PipeMod.ValueChangedFcn = createCallbackFcn(app, @PipeModValueChanged, true);
            app.PipeMod.Value = {'No Extra Modules'};
            app.UserInputsLabel = uilabel(app.UIFigure);
            app.UserInputsLabel.Text = '1. User Inputs';
            app.ExtraElectricTransportationCostModulesPanel = uipanel(app.UIFigure);
            app.ExtraElectricTransportationCostModulesPanel.Title = 'Extra Electric Transportation Cost Modules';
            app.ElecMod = uilistbox(app.ExtraElectricTransportationCostModulesPanel);
            app.ElecMod.Items = {'Consider Alternating Current', 'No Extra Modules'};
            app.ElecMod.Multiselect = 'on';
            app.ElecMod.ValueChangedFcn = createCallbackFcn(app, @ElecModValueChanged, true);
            app.ElecMod.Value = {'No Extra Modules'};
            app.ComputeCostsLabel = uilabel(app.UIFigure);
            app.ComputeCostsLabel.Text = '2. Compute Costs';
            app.ComputePipelineCostsButton = uibutton(app.UIFigure, 'push');
            app.ComputePipelineCostsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputePipelineCostsButtonPushed, true);
            app.ComputePipelineCostsButton.Enable = 'off';
            app.ComputePipelineCostsButton.Text = 'Compute Pipeline Costs';
            app.ComputeElectricCostsButton = uibutton(app.UIFigure, 'push');
            app.ComputeElectricCostsButton.Enable = 'off';
            app.ComputeElectricCostsButton.Text = 'Compute Electric Costs';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = '3. Results';
            app.CostsPipeLamp = uilamp(app.UIFigure);
            app.CostsElecLamp = uilamp(app.UIFigure);
            app.CostsPipeLabel = uilabel(app.UIFigure);
            app.CostsPipeLabel.Text = 'Costs Pipe not yet computed';
            app.CostsElecLabel = uilabel(app.UIFigure);
            app.CostsElecLabel.Text = 'Costs Electric not yet computed';
            app.ConsiderConstructionofnewPipelineandCableSwitchLabel = uilabel(app.UIFigure);
            app.ConsiderConstructionofnewPipelineandCableSwitchLabel.Text = 'Consider Construction of new Pipeline and Cable?';
            app.NewPipe = uiswitch(app.UIFigure, 'slider');
            app.NewPipe.Items = {'No construction', 'Construction'};
            app.NewPipe.ValueChangedFcn = createCallbackFcn(app, @NewPipeValueChanged, true);
            app.NewPipe.Value = 'No construction';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.UIAxesPipe = uiaxes(app.UIFigure);
            title(app.UIAxesPipe, 'CAPEX PIPE')
            ylabel(app.UIAxesPipe, 'Costs M€')
            app.UIAxesPipe2 = uiaxes(app.UIFigure);
            title(app.UIAxesPipe2, 'OPEX PIPE')
            ylabel(app.UIAxesPipe2, 'Costs M€')
            app.UIAxesElec = uiaxes(app.UIFigure);
            title(app.UIAxesElec, 'CAPEX ELEC')
            ylabel(app.UIAxesElec, 'Costs M€')
            app.UIAxesElec2 = uiaxes(app.UIFigure);
            title(app.UIAxesElec2, 'OPEX ELEC')
            ylabel(app.UIAxesElec2, 'Costs M€')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Capex Mod'; 'M€'; 'Opex Mod'; '€M'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true false];
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {''; ''};
            app.UITable2.RowName = {};
            app.UITableElec = uitable(app.UIFigure);
            app.UITableElec.ColumnName = {'Capex Mod'; 'M€'; 'Opex Mod'; '€M'};
            app.UITableElec.RowName = {};
            app.UITableElec.ColumnEditable = [false false true false];
            app.UITable2Elec_2 = uitable(app.UIFigure);
            app.UITable2Elec_2.ColumnName = {''; ''};
            app.UITable2Elec_2.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end